classdef tic_tac_toe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SIMPLETICTACTOEGAMELabel  matlab.ui.control.Label
        WinOrLose                 matlab.ui.control.NumericEditField
        Count                     matlab.ui.control.NumericEditField
        nutX                      matlab.ui.control.NumericEditField
        nutO                      matlab.ui.control.NumericEditField
        thuTu                     matlab.ui.control.NumericEditField
        Step                      matlab.ui.control.NumericEditField
        Panel                     matlab.ui.container.Panel
        RESETSCOREButton          matlab.ui.control.Button
        P2Point                   matlab.ui.control.NumericEditField
        P1Point                   matlab.ui.control.NumericEditField
        STARTButton               matlab.ui.control.StateButton
        Turn                      matlab.ui.control.Label
        P2Button                  matlab.ui.control.StateButton
        P1Button                  matlab.ui.control.StateButton
        WhoplaysfirstLabel        matlab.ui.control.Label
        RESETButton               matlab.ui.control.Button
        VSLabel                   matlab.ui.control.Label
        Player2Label              matlab.ui.control.Label
        Player1Label              matlab.ui.control.Label
        SCORELabel                matlab.ui.control.Label
        ClickButton_9             matlab.ui.control.StateButton
        ClickButton_8             matlab.ui.control.StateButton
        ClickButton_7             matlab.ui.control.StateButton
        ClickButton_6             matlab.ui.control.StateButton
        ClickButton_5             matlab.ui.control.StateButton
        ClickButton_4             matlab.ui.control.StateButton
        ClickButton_3             matlab.ui.control.StateButton
        ClickButton_2             matlab.ui.control.StateButton
        ClickButton               matlab.ui.control.StateButton
        EditField_9               matlab.ui.control.EditField
        EditField_8               matlab.ui.control.EditField
        EditField_7               matlab.ui.control.EditField
        EditField_6               matlab.ui.control.EditField
        EditField_5               matlab.ui.control.EditField
        EditField_4               matlab.ui.control.EditField
        EditField_3               matlab.ui.control.EditField
        EditField_2               matlab.ui.control.EditField
        EditField                 matlab.ui.control.EditField
        Panel_2                   matlab.ui.container.Panel
        Panel_3                   matlab.ui.container.Panel
        Panel_4                   matlab.ui.container.Panel
        Panel_8                   matlab.ui.container.Panel
        Panel_5                   matlab.ui.container.Panel
        Panel_9                   matlab.ui.container.Panel
        Panel_6                   matlab.ui.container.Panel
        Panel_7                   matlab.ui.container.Panel
        Panel_10                  matlab.ui.container.Panel
    end

    
    methods (Access = private)
       % Hàm kiểm tra thắng cuộc
        function checkWinner(app)
            winning_cases = [1 2 3; 4 5 6; 7 8 9; 1 4 7; 2 5 8; 3 6 9; 1 5 9; 3 5 7];
            board = {app.EditField.Value, app.EditField_2.Value, app.EditField_3.Value, ...
                     app.EditField_4.Value, app.EditField_5.Value, app.EditField_6.Value, ...
                     app.EditField_7.Value, app.EditField_8.Value, app.EditField_9.Value};
    
            for i = 1:size(winning_cases, 1)
                idx = winning_cases(i, :);
                if strcmp(board{idx(1)}, 'O') && strcmp(board{idx(2)}, 'O') && strcmp(board{idx(3)}, 'O')
                    app.WinOrLose.Value = 1;
                    app.Step.Value = 4;
                    return;
                elseif strcmp(board{idx(1)}, 'X') && strcmp(board{idx(2)}, 'X') && strcmp(board{idx(3)}, 'X')
                    app.WinOrLose.Value = 2;
                    app.Step.Value = 4;
                    return;
                end
            end 
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            if (app.STARTButton.Value == 1)
                app.Step.Value = 1;
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.Step.Value = 0;
            app.STARTButton.Value = 0;
            app.WinOrLose.Value = 0;
            app.thuTu.Value = 1; % Đặt lại người chơi bắt đầu
        

            % Reset bàn cờ
            app.EditField.Value = "";
            app.EditField_2.Value = "";
            app.EditField_3.Value = "";
            app.EditField_4.Value = "";
            app.EditField_5.Value = "";
            app.EditField_6.Value = "";
            app.EditField_7.Value = "";
            app.EditField_8.Value = "";
            app.EditField_9.Value = "";

            % Reset nút bấm
            app.ClickButton.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton.Text = "Click!";
            app.ClickButton.Value = 0;
            app.ClickButton_2.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_2.Text = "Click!";
            app.ClickButton_2.Value = 0;
            app.ClickButton_3.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_3.Text = "Click!";
            app.ClickButton_3.Value = 0;
            app.ClickButton_4.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_4.Text = "Click!";
            app.ClickButton_4.Value = 0;
            app.ClickButton_5.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_5.Text = "Click!";
            app.ClickButton_5.Value = 0;
            app.ClickButton_6.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_6.Text = "Click!";
            app.ClickButton_6.Value = 0;
            app.ClickButton_7.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_7.Text = "Click!";
            app.ClickButton_7.Value = 0;
            app.ClickButton_8.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_8.Text = "Click!";
            app.ClickButton_8.Value = 0;
            app.ClickButton_9.BackgroundColor = [0.90 0.90 0.90];
            app.ClickButton_9.Text = "Click!";
            app.ClickButton_9.Value = 0;
        
            % Reset hiển thị
            app.Turn.Visible = "off";
            app.P1Button.Visible = "on";
            app.P1Button.Text = "Player 1";
            app.P1Button.Value = 0;
            app.P2Button.Visible = "on";
            app.P2Button.Text = "Player 2";
            app.P2Button.Value = 0;
            app.WhoplaysfirstLabel.Text = "Who plays first?";

        end

        % Value changed function: ClickButton
        function ClickButtonValueChanged(app, event)

            if app.Step.Value == 3
                if (app.ClickButton.Value)
                    app.ClickButton.BackgroundColor = 'green';
                    app.ClickButton.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
        end

        % Value changed function: ClickButton_2
        function ClickButton_2ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_2.Value)
                    app.ClickButton_2.BackgroundColor = 'green';
                    app.ClickButton_2.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_2.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_2.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end

                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
        end

        % Value changed function: ClickButton_3
        function ClickButton_3ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_3.Value)
                    app.ClickButton_3.BackgroundColor = 'green';
                    app.ClickButton_3.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_3.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_3.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
            
        end

        % Value changed function: ClickButton_4
        function ClickButton_4ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_4.Value)
                    app.ClickButton_4.BackgroundColor = 'green';
                    app.ClickButton_4.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_4.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_4.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
            
        end

        % Value changed function: ClickButton_5
        function ClickButton_5ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_5.Value)
                    app.ClickButton_5.BackgroundColor = 'green';
                    app.ClickButton_5.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_5.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_5.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
            
        end

        % Value changed function: ClickButton_6
        function ClickButton_6ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_6.Value)
                    app.ClickButton_6.BackgroundColor = 'green';
                    app.ClickButton_6.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_6.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_6.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
            
        end

        % Value changed function: ClickButton_7
        function ClickButton_7ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_7.Value)
                    app.ClickButton_7.BackgroundColor = 'green';
                    app.ClickButton_7.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_7.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_7.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
        end

        % Value changed function: ClickButton_8
        function ClickButton_8ValueChanged(app, event)
            if app.Step.Value == 3
                if (app.ClickButton_8.Value)
                    app.ClickButton_8.BackgroundColor = 'green';
                    app.ClickButton_8.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_8.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_8.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
            
        end

        % Value changed function: ClickButton_9
        function ClickButton_9ValueChanged(app, event)

            if app.Step.Value == 3
                if (app.ClickButton_9.Value)
                    app.ClickButton_9.BackgroundColor = 'green';
                    app.ClickButton_9.Text = "Chose";
                    if (app.thuTu.Value == app.nutO.Value)
                        app.EditField_9.Value = "O";
                    elseif (app.thuTu.Value == app.nutX.Value)
                        app.EditField_9.Value = "X";
                    end
                    if (app.thuTu.Value == 1)
                        app.thuTu.Value = 2;
                    elseif (app.thuTu.Value == 2)
                        app.thuTu.Value = 1;
                    end
                end
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                % Kiểm tra thắng sau mỗi lượt
                checkWinner(app);

                % Cập nhật điểm số nếu có người thắng
                if app.Step.Value == 4
                    if app.WinOrLose.Value == 1
                        app.P1Point.Value = app.P1Point.Value + 1;
                    elseif app.WinOrLose.Value == 2
                        app.P2Point.Value = app.P2Point.Value + 1;
                    end
                end
            end
            
        end

        % Value changed function: P1Button
        function P1ButtonValueChanged(app, event)
            if app.Step.Value == 0
                app.P1Button.Value = 0;
            end
            if app.Step.Value == 1 && app.P1Button.Value == 1
                app.WhoplaysfirstLabel.Text = "Player 1";
                app.P1Button.Text = "O";
                app.P2Button.Text = "X";
                app.thuTu.Value = 1;
                app.Step.Value = 2;
                app.P1Button.Value = 0;
            end
            
            if app.Step.Value == 2 && app.P1Button.Value == 1
                app.nutO.Value = app.thuTu.Value;
                if (app.thuTu.Value == 1)
                    app.nutX.Value = 2;
                elseif (app.thuTu.Value == 2)
                    app.nutX.Value = 1;
                end
                app.WhoplaysfirstLabel.Text = "Lets Go";
                app.P1Button.Visible = "off";
                app.P2Button.Visible = "off";
                app.Step.Value = 3;
                app.Turn.Visible = "on";
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
                
            end
        end

        % Value changed function: P2Button
        function P2ButtonValueChanged(app, event)
            if app.Step.Value == 0
                app.P2Button.Value = 0;
            end
            if app.Step.Value == 1 && app.P2Button.Value == 1
                app.WhoplaysfirstLabel.Text = "Player 2";
                app.P1Button.Text = "O";
                app.P2Button.Text = "X";
                app.thuTu.Value = 2;
                app.Step.Value = 2;
                app.P2Button.Value = 0;
            end
            
            if app.Step.Value == 2 && app.P2Button.Value == 1
                app.nutX.Value = app.thuTu.Value;
                 if (app.thuTu.Value == 1)
                    app.nutO.Value = 2;
                elseif (app.thuTu.Value == 2)
                    app.nutO.Value = 1;
                 end
                app.WhoplaysfirstLabel.Text = "Lets Go";
                app.P1Button.Visible = "off";
                app.P2Button.Visible = "off";
                app.Step.Value = 3;
                app.Turn.Visible = "on";
                if app.thuTu.Value == 1 && app.Step.Value == 3
                    app.Turn.Text = "P1's Turn";
                elseif app.thuTu.Value == 2 && app.Step.Value == 3
                    app.Turn.Text = "P2's Turn";
                end
            end
            
        end

        % Button pushed function: RESETSCOREButton
        function RESETSCOREButtonPushed(app, event)
            app.P1Point.Value = 0;
            app.P2Point.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_10 = uipanel(app.Panel);
            app.Panel_7 = uipanel(app.Panel);
            app.Panel_6 = uipanel(app.Panel);
            app.Panel_9 = uipanel(app.Panel);
            app.Panel_5 = uipanel(app.Panel);
            app.Panel_8 = uipanel(app.Panel);
            app.Panel_4 = uipanel(app.Panel);
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_2 = uipanel(app.Panel);
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.EditField_5 = uieditfield(app.Panel, 'text');
            app.EditField_6 = uieditfield(app.Panel, 'text');
            app.EditField_7 = uieditfield(app.Panel, 'text');
            app.EditField_8 = uieditfield(app.Panel, 'text');
            app.EditField_9 = uieditfield(app.Panel, 'text');
            app.ClickButton = uibutton(app.Panel, 'state');
            app.ClickButton.ValueChangedFcn = createCallbackFcn(app, @ClickButtonValueChanged, true);
            app.ClickButton.Text = 'Click!';
            app.ClickButton_2 = uibutton(app.Panel, 'state');
            app.ClickButton_2.ValueChangedFcn = createCallbackFcn(app, @ClickButton_2ValueChanged, true);
            app.ClickButton_2.Text = 'Click!';
            app.ClickButton_3 = uibutton(app.Panel, 'state');
            app.ClickButton_3.ValueChangedFcn = createCallbackFcn(app, @ClickButton_3ValueChanged, true);
            app.ClickButton_3.Text = 'Click!';
            app.ClickButton_4 = uibutton(app.Panel, 'state');
            app.ClickButton_4.ValueChangedFcn = createCallbackFcn(app, @ClickButton_4ValueChanged, true);
            app.ClickButton_4.Text = 'Click!';
            app.ClickButton_5 = uibutton(app.Panel, 'state');
            app.ClickButton_5.ValueChangedFcn = createCallbackFcn(app, @ClickButton_5ValueChanged, true);
            app.ClickButton_5.Text = 'Click!';
            app.ClickButton_6 = uibutton(app.Panel, 'state');
            app.ClickButton_6.ValueChangedFcn = createCallbackFcn(app, @ClickButton_6ValueChanged, true);
            app.ClickButton_6.Text = 'Click!';
            app.ClickButton_7 = uibutton(app.Panel, 'state');
            app.ClickButton_7.ValueChangedFcn = createCallbackFcn(app, @ClickButton_7ValueChanged, true);
            app.ClickButton_7.Text = 'Click!';
            app.ClickButton_8 = uibutton(app.Panel, 'state');
            app.ClickButton_8.ValueChangedFcn = createCallbackFcn(app, @ClickButton_8ValueChanged, true);
            app.ClickButton_8.Text = 'Click!';
            app.ClickButton_9 = uibutton(app.Panel, 'state');
            app.ClickButton_9.ValueChangedFcn = createCallbackFcn(app, @ClickButton_9ValueChanged, true);
            app.ClickButton_9.Text = 'Click!';
            app.SCORELabel = uilabel(app.Panel);
            app.SCORELabel.Text = 'SCORE';
            app.Player1Label = uilabel(app.Panel);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.Panel);
            app.Player2Label.Text = 'Player 2';
            app.VSLabel = uilabel(app.Panel);
            app.VSLabel.Text = 'VS';
            app.RESETButton = uibutton(app.Panel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.WhoplaysfirstLabel = uilabel(app.Panel);
            app.WhoplaysfirstLabel.Text = 'Who plays first?';
            app.P1Button = uibutton(app.Panel, 'state');
            app.P1Button.ValueChangedFcn = createCallbackFcn(app, @P1ButtonValueChanged, true);
            app.P1Button.Text = 'Player 1';
            app.P2Button = uibutton(app.Panel, 'state');
            app.P2Button.ValueChangedFcn = createCallbackFcn(app, @P2ButtonValueChanged, true);
            app.P2Button.Text = 'Player 2';
            app.Turn = uilabel(app.Panel);
            app.Turn.Visible = 'off';
            app.STARTButton = uibutton(app.Panel, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.P1Point = uieditfield(app.Panel, 'numeric');
            app.P2Point = uieditfield(app.Panel, 'numeric');
            app.RESETSCOREButton = uibutton(app.Panel, 'push');
            app.RESETSCOREButton.ButtonPushedFcn = createCallbackFcn(app, @RESETSCOREButtonPushed, true);
            app.RESETSCOREButton.Text = {'RESET'; 'SCORE'};
            app.Step = uieditfield(app.UIFigure, 'numeric');
            app.Step.Visible = 'off';
            app.thuTu = uieditfield(app.UIFigure, 'numeric');
            app.thuTu.Visible = 'off';
            app.nutO = uieditfield(app.UIFigure, 'numeric');
            app.nutO.Visible = 'off';
            app.nutX = uieditfield(app.UIFigure, 'numeric');
            app.nutX.Visible = 'off';
            app.Count = uieditfield(app.UIFigure, 'numeric');
            app.Count.Visible = 'off';
            app.WinOrLose = uieditfield(app.UIFigure, 'numeric');
            app.WinOrLose.Visible = 'off';
            app.SIMPLETICTACTOEGAMELabel = uilabel(app.UIFigure);
            app.SIMPLETICTACTOEGAMELabel.Text = 'SIMPLE TIC TAC TOE GAME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tic_tac_toe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end